classdef EMSO_solarPV_model_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BuildingModelGeneratorUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        InstructionsLabel               matlab.ui.control.Label
        sub_instructionsLabel           matlab.ui.control.Label
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        MovementIncrementdegSpinnerLabel  matlab.ui.control.Label
        MovementIncrementdegSpinner     matlab.ui.control.Spinner
        WestButton                      matlab.ui.control.Button
        NorthButton                     matlab.ui.control.Button
        EastButton                      matlab.ui.control.Button
        SouthButton                     matlab.ui.control.Button
        GENERATEMODELSButton            matlab.ui.control.Button
        ReturntoOptimizerPathSettingsButton  matlab.ui.control.Button
        BuildingNameLabel               matlab.ui.control.Label
        BuildingNameEditField           matlab.ui.control.EditField
        AddtoQueueButton                matlab.ui.control.Button
        RemoveSelectionfromQueueButton  matlab.ui.control.Button
        ZoomInButton                    matlab.ui.control.Button
        ZoomOutButton                   matlab.ui.control.Button
        QueueListBoxLabel               matlab.ui.control.Label
        QueueListBox                    matlab.ui.control.ListBox
        POSITIONSettingsLabel           matlab.ui.control.Label
        CurrentQueueLabel               matlab.ui.control.Label
        APIKeyEditFieldLabel            matlab.ui.control.Label
        APIKeyEditField                 matlab.ui.control.EditField
        CAMERAMovementLabel             matlab.ui.control.Label
        APIKey_errorTextArea            matlab.ui.control.TextArea
        APIKey_error_URLTextArea        matlab.ui.control.TextArea
        APIKey_errorTextArea_2          matlab.ui.control.TextArea
        DelayMultiplierSpinnerLabel     matlab.ui.control.Label
        DelayMultiplierSpinner          matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        lat_default = 53.5281333;       % Default latitude
        lon_default = -113.5256635;     % Default longitude
        zoom_default = 19;              % Default zoom 
        zm;                             % Zoom
        names_list;                     % List of names of each building in queue
        latitudes_list;                 % List of corresponding latitudes for each building in queue
        URLs_list;                      % List of direct Google Maps URLs of each building in queue
        path_mesh;
        path_imag;
        path_save;
        path_output;
    end
    
    methods (Access = private)
        
        function refresh_GMap(app)            
            % Refreshes Google Maps GUI based on current position/zoom settings
            plot_GMap('axis', app.UIAxes, ...
                      'apiKey', app.APIKeyEditField.Value, ...
                      'width',640,'height',640, ...
                      'maptype','satellite', ...
                      'MapScale', 0, 'scale',1, ...
                      'autoaxis',0, ...
                      'lat',app.LatitudeEditField.Value, ...
                      'lon',app.LongitudeEditField.Value, ...
                      'zoom',app.zm);
            % Check for APIKey
            if exist('api_key.mat','file')
                load('api_key.mat','apiKey','apiKey_valid');
            else
                apiKey = '';
                apiKey_valid = false;
            end
            % Check if connection to Google Maps was successful
            if ~apiKey_valid
                app.APIKey_errorTextArea.Visible = true;
                app.APIKey_errorTextArea_2.Visible = true;
                app.APIKey_error_URLTextArea.Visible = true;
                URL = "http://maps.googleapis.com/maps/api/staticmap?center=" ...
                      +num2str(app.LatitudeEditField.Value,10)+","+num2str(app.LongitudeEditField.Value,10) ...
                      +"&zoom="+num2str(app.zm)+"&scale=1&size=640x640&maptype=satellite&format=jpg&key="+apiKey;
                app.APIKey_error_URLTextArea.Value = URL;
            else
                app.APIKey_errorTextArea.Visible = false;
                app.APIKey_errorTextArea_2.Visible = false;
                app.APIKey_error_URLTextArea.Visible = false;
            end
        end
        
        function refresh_buttons(app)
            % Enable/disable GENERATE button based on queue
            if ~isempty(app.QueueListBox.Items)
                app.GENERATEMODELSButton.Enable = true;
            else
                app.GENERATEMODELSButton.Enable = false;
            end
            
            % Enable/disable ADD TO QUEUE button based on user-specified building name
            if ~isempty(app.BuildingNameEditField.Value)
                app.AddtoQueueButton.Enable = true;
            else
                app.AddtoQueueButton.Enable = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Delete all values in queue
            app.QueueListBox.Items = {};
            
            % Set initial values for Google Maps UI
            app.zm = app.zoom_default;
            app.LatitudeEditField.Value = app.lat_default;
            app.LongitudeEditField.Value = app.lon_default;
            
            % Write instructions at top
            app.InstructionsLabel.Text = "Navigate until desired building is mostly within frame.";
            app.sub_instructionsLabel.Text = "Keep the building edges close to outer image frame. Some overflow is expected.";
            
            % Check for APIKey
            if exist('api_key.mat','file')
                load api_key
            else
                apiKey = '';
            end
            app.APIKeyEditField.Value = apiKey;
            
            % Load queue from file; remove any entries that already have
            % files
            if exist('EMSO_solarPV_model_generator_queue.mat','file')
                load('EMSO_solarPV_model_generator_queue.mat','names_list','latitudes_list','URLs_list','queue_display');
                app.names_list = names_list;
                app.latitudes_list = latitudes_list;
                app.URLs_list = URLs_list;
                app.QueueListBox.Items = queue_display;
            end
            
            % Load custom paths, if any
            if exist("EMSO_solarPV_optimizer_custompaths.mat",'file')
                load("EMSO_solarPV_optimizer_custompaths.mat",'path_mesh','path_imag','path_save','path_output');
                app.path_mesh = path_mesh;
                app.path_imag = path_imag;
                app.path_save = path_save;
                app.path_output = path_output;
            else
                app.path_mesh = pwd+"\meshes\";
                app.path_imag = pwd+"\images\";
                app.path_save = pwd+"\output\";
                app.path_output = pwd+"\output\transfer\";
            end
            
            % Refresh map and buttons
            app.refresh_GMap
            app.refresh_buttons
        end

        % Button pushed function: NorthButton
        function NorthButtonPushed(app, event)
            app.LatitudeEditField.Value = app.LatitudeEditField.Value + app.MovementIncrementdegSpinner.Value;
            app.refresh_GMap
        end

        % Button pushed function: SouthButton
        function SouthButtonPushed(app, event)
            app.LatitudeEditField.Value = app.LatitudeEditField.Value - app.MovementIncrementdegSpinner.Value;
            app.refresh_GMap
        end

        % Button pushed function: WestButton
        function WestButtonPushed(app, event)
            app.LongitudeEditField.Value = app.LongitudeEditField.Value - app.MovementIncrementdegSpinner.Value;
            app.refresh_GMap
        end

        % Button pushed function: EastButton
        function EastButtonPushed(app, event)
            app.LongitudeEditField.Value = app.LongitudeEditField.Value + app.MovementIncrementdegSpinner.Value;
            app.refresh_GMap
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            app.zm = app.zm + 1;
            if app.zm > 21
                app.zm = 21;
            end
            app.refresh_GMap
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            app.zm = app.zm - 1;
            if app.zm < 1
                app.zm = 1;
            end
            app.refresh_GMap
        end

        % Value changed function: MovementIncrementdegSpinner
        function MovementIncrementdegSpinnerValueChanged(app, event)
            value = app.MovementIncrementdegSpinner.Value;
            
        end

        % Button pushed function: AddtoQueueButton
        function AddtoQueueButtonPushed(app, event)
            name = string(app.BuildingNameEditField.Value);
            latitude = app.LatitudeEditField.Value;
            URL = "https://www.google.com/maps/@?api=1&map_action=map&center=" ...
                  +num2str(app.LatitudeEditField.Value,10)+","+num2str(app.LongitudeEditField.Value,10) ...
                  +"&zoom="+num2str(min(app.zm,21))+"&basemap=satellite";
            queue_display = name+" ("+num2str(app.LatitudeEditField.Value,10)+","+num2str(app.LongitudeEditField.Value,10)+", zoom="+num2str(min(app.zm,21))+")";
            
            % Workaround for array dimension errors
            if isempty(app.names_list)
                app.names_list = string([]);
            end
            if isempty(app.URLs_list)
                app.URLs_list = string([]);
            end
            if isempty(app.latitudes_list)
                app.latitudes_list = string([]);
            end
            
            % Check if name+URL are already in queue display
            if ismember(queue_display,app.QueueListBox.Items)
                app.QueueListBox.Value = {queue_display};
            elseif ismember(name,app.names_list)
                find(ismember(name,app.names_list))
                app.QueueListBox.Value = app.QueueListBox.Items{find(ismember(name,app.names_list))};
            else
                app.BuildingNameEditField.Value = "";
                app.names_list = [string(app.names_list); name];
                app.latitudes_list = [app.latitudes_list; latitude];
                app.URLs_list  = [app.URLs_list; URL];
                app.QueueListBox.Items = [app.QueueListBox.Items, queue_display];
            end
            
            % Save queue to file
            names_list = app.names_list;
            latitudes_list = app.latitudes_list;
            URLs_list = app.URLs_list;
            queue_display = app.QueueListBox.Items;
            save('EMSO_solarPV_model_generator_queue.mat','names_list','latitudes_list','URLs_list','queue_display');
            
            % Update button availability
            app.refresh_buttons
        end

        % Button pushed function: RemoveSelectionfromQueueButton
        function RemoveSelectionfromQueueButtonPushed(app, event)
%             app.QueueListBox.Items
%             app.QueueListBox.Value
            idx_delete = ismember(app.QueueListBox.Items, app.QueueListBox.Value);
            
            app.names_list(idx_delete) = [];
            app.latitudes_list(idx_delete) = [];
            app.URLs_list(idx_delete) = [];
            app.QueueListBox.Items(idx_delete) = [];
            
            % Save queue to file
            names_list = app.names_list;
            latitudes_list = app.latitudes_list;
            URLs_list = app.URLs_list;
            queue_display = app.QueueListBox.Items;
            save('EMSO_solarPV_model_generator_queue.mat','names_list','latitudes_list','URLs_list','queue_display');
            
            % Update button availability
            app.refresh_buttons
        end

        % Value changed function: APIKeyEditField
        function APIKeyEditFieldValueChanged(app, event)
            app.refresh_GMap
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)
            app.refresh_GMap
        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
            app.refresh_GMap
        end

        % Value changed function: BuildingNameEditField
        function BuildingNameEditFieldValueChanged(app, event)
            app.refresh_buttons
        end

        % Value changing function: BuildingNameEditField
        function BuildingNameEditFieldValueChanging(app, event)
            app.refresh_buttons
        end

        % Button pushed function: GENERATEMODELSButton
        function GENERATEMODELSButtonPushed(app, event)
            % Disable EVERYTHING until script is complete.
            app.APIKeyEditField.Enable = false;
            app.LatitudeEditField.Enable = false;
            app.LongitudeEditField.Enable = false;
            app.MovementIncrementdegSpinner.Enable = false;
            app.WestButton.Enable = false;
            app.NorthButton.Enable = false;
            app.EastButton.Enable = false;
            app.SouthButton.Enable = false;
            app.ZoomInButton.Enable = false;
            app.ZoomOutButton.Enable = false;
            app.BuildingNameEditField.Enable = false;
            app.AddtoQueueButton.Enable = false;
            app.QueueListBox.Enable = false;
            app.RemoveSelectionfromQueueButton.Enable = false;
            app.ReturntoOptimizerPathSettingsButton.Enable = false;
            app.GENERATEMODELSButton.Enable = false;
            app.GENERATEMODELSButton.Text = "RUNNING";
            app.DelayMultiplierSpinner.Enable = false;
            
            % Generate cell array of building names and URLs!
            names = app.names_list;
            latitudes = app.latitudes_list;
            urls = app.URLs_list;
            building_URLs = cellstr([urls,names]);
            
            % Create (mostly-)empty file that will contain building
            % names + latitudes.
            fname_lats = app.path_save+"\EMSO_solarPV_buildings.mat";
            if ~exist(fname_lats,'file')
                names_saved = [];
                latitudes_saved = [];
                save(fname_lats,"names_saved","latitudes_saved");
            end
            % Save building names + latitudes!
            load(fname_lats,"names_saved","latitudes_saved");
                % Remove any saved buildings with matching names
                if ~isempty(names_saved)
                    idx_delete = ismember(names_saved,[names]);
                    names_saved(idx_delete) = [];
                    latitudes_saved(idx_delete) = [];
                end
                % Workaround for array dimension errors, if the entire list
                % gets deleted
                if isempty(names_saved)
                    names_saved = string([]);
                end
                if isempty(latitudes_saved)
                    latitudes_saved = string([]);
                end
            names_saved     = [names_saved;names];
            latitudes_saved = [latitudes_saved;latitudes];
            save(fname_lats,"names_saved","latitudes_saved");            
            
            % GENERATE MODELS!
            automate_model_generation(building_URLs,'timer_mult',app.DelayMultiplierSpinner.Value, ...
                                      'path_meshes',app.path_mesh, ...
                                      'path_images',app.path_imag, ...
                                      'path_RDC',app.path_mesh, ...
                                      'path_buttons',app.path_save);
            questdlg("Model generation complete!"+newline+"If no further models will be generated, you may close Blender, Chromium, and RenderDoc.","3D Model Generation Completion","Ok","Ok");
            
            % Re-enable EVERYTHING!
            app.APIKeyEditField.Enable = true;
            app.LatitudeEditField.Enable = true;
            app.LongitudeEditField.Enable = true;
            app.MovementIncrementdegSpinner.Enable = true;
            app.WestButton.Enable = true;
            app.NorthButton.Enable = true;
            app.EastButton.Enable = true;
            app.SouthButton.Enable = true;
            app.ZoomInButton.Enable = true;
            app.ZoomOutButton.Enable = true;
            app.BuildingNameEditField.Enable = true;
            app.AddtoQueueButton.Enable = true;
            app.QueueListBox.Enable = true;
            app.RemoveSelectionfromQueueButton.Enable = true;
            app.ReturntoOptimizerPathSettingsButton.Enable = true;
            app.GENERATEMODELSButton.Enable = true;
            app.GENERATEMODELSButton.Text = "GENERATE"+newline+"MODELS";
            app.DelayMultiplierSpinner.Enable = true;
            
            app.refresh_buttons
            app.refresh_GMap
        end

        % Button pushed function: 
        % ReturntoOptimizerPathSettingsButton
        function ReturntoOptimizerPathSettingsButtonPushed(app, event)
            EMSO_solarPV_optimizer
            close(app.BuildingModelGeneratorUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BuildingModelGeneratorUIFigure = uifigure('Visible', 'off');
            app.BuildingModelGeneratorUIFigure.Name = 'Building Model Generator';
            app.GridLayout = uigridlayout(app.BuildingModelGeneratorUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'GoogleMapsUI')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InstructionsLabel = uilabel(app.GridLayout);
            app.InstructionsLabel.Text = 'Instructions';
            app.sub_instructionsLabel = uilabel(app.GridLayout);
            app.sub_instructionsLabel.Text = 'sub_instructions';
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.GridLayout, 'numeric');
            app.LatitudeEditField.ValueDisplayFormat = '%.7f';
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.GridLayout, 'numeric');
            app.LongitudeEditField.ValueDisplayFormat = '%.7f';
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.MovementIncrementdegSpinnerLabel = uilabel(app.GridLayout);
            app.MovementIncrementdegSpinnerLabel.Text = 'Movement Increment (deg)';
            app.MovementIncrementdegSpinner = uispinner(app.GridLayout);
            app.MovementIncrementdegSpinner.Step = 0.0001;
            app.MovementIncrementdegSpinner.Limits = [0 Inf];
            app.MovementIncrementdegSpinner.ValueDisplayFormat = '%.4f';
            app.MovementIncrementdegSpinner.ValueChangedFcn = createCallbackFcn(app, @MovementIncrementdegSpinnerValueChanged, true);
            app.MovementIncrementdegSpinner.Value = 0.0006;
            app.WestButton = uibutton(app.GridLayout, 'push');
            app.WestButton.ButtonPushedFcn = createCallbackFcn(app, @WestButtonPushed, true);
            app.WestButton.Text = 'West';
            app.NorthButton = uibutton(app.GridLayout, 'push');
            app.NorthButton.ButtonPushedFcn = createCallbackFcn(app, @NorthButtonPushed, true);
            app.NorthButton.Text = 'North';
            app.EastButton = uibutton(app.GridLayout, 'push');
            app.EastButton.ButtonPushedFcn = createCallbackFcn(app, @EastButtonPushed, true);
            app.EastButton.Text = 'East';
            app.SouthButton = uibutton(app.GridLayout, 'push');
            app.SouthButton.ButtonPushedFcn = createCallbackFcn(app, @SouthButtonPushed, true);
            app.SouthButton.Text = 'South';
            app.GENERATEMODELSButton = uibutton(app.GridLayout, 'push');
            app.GENERATEMODELSButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEMODELSButtonPushed, true);
            app.GENERATEMODELSButton.Tooltip = {'Generates models for all buildings in queue'};
            app.GENERATEMODELSButton.Text = {'GENERATE '; 'MODELS'};
            app.ReturntoOptimizerPathSettingsButton = uibutton(app.GridLayout, 'push');
            app.ReturntoOptimizerPathSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoOptimizerPathSettingsButtonPushed, true);
            app.ReturntoOptimizerPathSettingsButton.Text = {'Return to Optimizer /'; 'Path Settings'};
            app.BuildingNameLabel = uilabel(app.GridLayout);
            app.BuildingNameLabel.Text = {'Building '; 'Name'};
            app.BuildingNameEditField = uieditfield(app.GridLayout, 'text');
            app.BuildingNameEditField.ValueChangedFcn = createCallbackFcn(app, @BuildingNameEditFieldValueChanged, true);
            app.BuildingNameEditField.ValueChangingFcn = createCallbackFcn(app, @BuildingNameEditFieldValueChanging, true);
            app.BuildingNameEditField.Value = 'NAME';
            app.AddtoQueueButton = uibutton(app.GridLayout, 'push');
            app.AddtoQueueButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoQueueButtonPushed, true);
            app.AddtoQueueButton.Text = {'Add to '; 'Queue'};
            app.RemoveSelectionfromQueueButton = uibutton(app.GridLayout, 'push');
            app.RemoveSelectionfromQueueButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectionfromQueueButtonPushed, true);
            app.RemoveSelectionfromQueueButton.Text = {'Remove '; 'Selection'; 'from '; 'Queue'};
            app.ZoomInButton = uibutton(app.GridLayout, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = 'Zoom In';
            app.ZoomOutButton = uibutton(app.GridLayout, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.QueueListBoxLabel = uilabel(app.GridLayout);
            app.QueueListBoxLabel.Text = 'Queue';
            app.QueueListBox = uilistbox(app.GridLayout);
            app.QueueListBox.Items = {'Item1', 'Item2', 'Item3'};
            app.QueueListBox.Multiselect = 'on';
            app.QueueListBox.Value = {};
            app.POSITIONSettingsLabel = uilabel(app.GridLayout);
            app.POSITIONSettingsLabel.Text = 'POSITION Settings';
            app.CurrentQueueLabel = uilabel(app.GridLayout);
            app.CurrentQueueLabel.Text = 'Current Queue';
            app.APIKeyEditFieldLabel = uilabel(app.GridLayout);
            app.APIKeyEditFieldLabel.Text = 'API Key';
            app.APIKeyEditField = uieditfield(app.GridLayout, 'text');
            app.APIKeyEditField.ValueChangedFcn = createCallbackFcn(app, @APIKeyEditFieldValueChanged, true);
            app.APIKeyEditField.Value = 'test';
            app.CAMERAMovementLabel = uilabel(app.GridLayout);
            app.CAMERAMovementLabel.Text = 'CAMERA Movement';
            app.APIKey_errorTextArea = uitextarea(app.GridLayout);
            app.APIKey_errorTextArea.Editable = 'off';
            app.APIKey_errorTextArea.Value = {'WARNING: Unable to download map from Google Servers.'};
            app.APIKey_error_URLTextArea = uitextarea(app.GridLayout);
            app.APIKey_error_URLTextArea.Editable = 'off';
            app.APIKey_error_URLTextArea.Value = {'http://maps.googleapis.com/maps/api/staticmap?center=53.5281333,-113.5256635&zoom=19&scale=1&size=640x640&maptype=satellite&format=jpg&key=ZZZZZZZZZZZZZZZZZZZZZZZZZZZZZZZZZZZZ'};
            app.APIKey_errorTextArea_2 = uitextarea(app.GridLayout);
            app.APIKey_errorTextArea_2.Editable = 'off';
            app.APIKey_errorTextArea_2.Value = {'Possible reasons: '; '- invalid API Key'; '- API Key does not have `Maps Static API` enabled'; '- internet connection not working'; ''; 'To debug, try pasting the URL below into your browser:'};
            app.DelayMultiplierSpinnerLabel = uilabel(app.GridLayout);
            app.DelayMultiplierSpinnerLabel.Text = {'Delay'; 'Multiplier'};
            app.DelayMultiplierSpinner = uispinner(app.GridLayout);
            app.DelayMultiplierSpinner.Step = 0.1;
            app.DelayMultiplierSpinner.Limits = [0.5 Inf];
            app.DelayMultiplierSpinner.Tooltip = {'Multiplies "pause" durations during model generation.'; 'Higher values are slower, but safer for slow computers.'};
            app.DelayMultiplierSpinner.Value = 1;
            % Show the figure after all components are created
            app.BuildingModelGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMSO_solarPV_model_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BuildingModelGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BuildingModelGeneratorUIFigure)
        end
    end
end