classdef EMSO_solarPV_optimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RooftopPVOptimizerforEMSOUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        APPSettingsLabel                matlab.ui.control.Label
        PixelLengthEditFieldLabel       matlab.ui.control.Label
        PixelLengthEditField            matlab.ui.control.NumericEditField
        PANELWALKWAYSettingsLabel       matlab.ui.control.Label
        PanelUnitDropDown               matlab.ui.control.DropDown
        PanellengthEditFieldLabel       matlab.ui.control.Label
        PanellengthEditField            matlab.ui.control.EditField
        PanelwidthEditFieldLabel        matlab.ui.control.Label
        PanelwidthEditField             matlab.ui.control.EditField
        PaneltiltEditFieldLabel         matlab.ui.control.Label
        PaneltiltEditField              matlab.ui.control.NumericEditField
        PanelTiltUnitBox                matlab.ui.control.Button
        PanelOrientationDropDown        matlab.ui.control.DropDown
        WalkwayminwidthEditFieldLabel   matlab.ui.control.Label
        WalkwayminwidthEditField        matlab.ui.control.NumericEditField
        WalkwayUnitDropDown             matlab.ui.control.DropDown
        ForcepolygonapproximationLabel  matlab.ui.control.Label
        SelectBuildingsListBoxLabel     matlab.ui.control.Label
        SelectBuildingsListBox          matlab.ui.control.ListBox
        checkbox_polyapprox             matlab.ui.control.CheckBox
        ForceshadowstudyLabel           matlab.ui.control.Label
        checkbox_shadows                matlab.ui.control.CheckBox
        ShadowstudyresolutionscaleEditFieldLabel  matlab.ui.control.Label
        ShadowstudyresolutionscaleEditField  matlab.ui.control.NumericEditField
        path_meshButton                 matlab.ui.control.Button
        path_imagesButton               matlab.ui.control.Button
        path_outputButton               matlab.ui.control.Button
        path_outputtransferButton       matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        PanelorientationLabel           matlab.ui.control.Label
        InputmeshpathEditFieldLabel     matlab.ui.control.Label
        InputmeshpathEditField          matlab.ui.control.EditField
        InputsatellitephotopathEditFieldLabel  matlab.ui.control.Label
        InputsatellitephotopathEditField  matlab.ui.control.EditField
        OutputsavefilepathEditFieldLabel  matlab.ui.control.Label
        OutputsavefilepathEditField     matlab.ui.control.EditField
        OutputpathEditFieldLabel        matlab.ui.control.Label
        OutputpathEditField             matlab.ui.control.EditField
        GenerateNew3DModelsButton       matlab.ui.control.Button
        ResetPathstoDefaultButton       matlab.ui.control.Button
        GeneratefiguresLabel            matlab.ui.control.Label
        checkbox_plot                   matlab.ui.control.CheckBox
        AllowcustommaskLabel            matlab.ui.control.Label
        checkbox_allow_custom_mask      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        buildings       % List of selected buildings
        clrsky          % Nima's clear sky index table
        path_mesh_d     % Default mesh path
        path_imag_d     % Default satellite photo path
        path_save_d     % Default output "temporary savefile" path
        path_output_d   % Default output path (Building info files, etc)
    end
    
    methods (Access = private)
        
        function refresh_buildings_list(app)
            % Establish buildings from updated file path
            names_stl  = file_exists(app.InputmeshpathEditField.Value,".stl");
            names_png  = file_exists(app.InputsatellitephotopathEditField.Value,".png");
            names_jpg  = file_exists(app.InputsatellitephotopathEditField.Value,".jpg");
            names_jpeg = file_exists(app.InputsatellitephotopathEditField.Value,".jpeg");
            names_bmp  = file_exists(app.InputsatellitephotopathEditField.Value,".bmp");
            buildings_list = [];
            for i = 1:size(names_stl,1)
                name = names_stl(i);
                if ismember(name,names_stl) && (ismember(name,names_png) || ismember(name,names_jpg) || ismember(name,names_jpeg) || ismember(name,names_bmp))
                    buildings_list = [buildings_list;name];
                end
            end
            if isempty(buildings_list)
                buildings_list = ["ERROR: No buildings found"];
                app.STARTButton.Enable = false;
            else
                app.STARTButton.Enable = true;
            end
            app.SelectBuildingsListBox.Items = buildings_list;
        end
        
        function save_paths(app)
            % Saves paths to a .mat file, for subsequent uses.
            path_mesh = app.InputmeshpathEditField.Value;
            path_imag = app.InputsatellitephotopathEditField.Value;
            path_save = app.OutputsavefilepathEditField.Value;
            path_output = app.OutputpathEditField.Value;
            save("EMSO_solarPV_optimizer_custompaths.mat",'path_mesh','path_imag','path_save','path_output');            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide buttons
            
            % Establish buildings if all necessary files exist
%             buildings_list = ["ADMIN"; "BioSci";  "CAM"; "CCIS"; "DICE"; ...
%                               "ETLC";     "GSB"; "NINT"; "NREF";  "SUB"; ...
%                               "Pembina"; "VVC"];
%             error("`buildings_list` is currently a pre-determined list. If GMaps integration is far off, let this list be whatever buildings appear in `meshes`!")
            
%             names_stl = file_exists('meshes\',".stl");
%             names_png = file_exists("images",".png");
%             names_jpg = file_exists("images",".jpg");
%             names_jpeg = file_exists("images",".jpeg");
%             names_bmp = file_exists("images",".bmp");
%             buildings_list = [];
%             for i = 1:size(names_stl,1)
%                 name = names_stl(i);
%                 if ismember(name,names_stl) && (ismember(name,names_png) || ismember(name,names_jpg) || ismember(name,names_jpeg) || ismember(name,names_bmp))
%                     buildings_list = [buildings_list;name];
%                 end
%             end
% 
%             app.SelectBuildingsListBox.Items = buildings_list;
            
            % Establish default input/output paths
            app.path_mesh_d = pwd+"\meshes\";
            app.path_imag_d = pwd+"\images\";
            app.path_save_d = pwd+"\output\";
            app.path_output_d = pwd+"\output\transfer\";
            % Populate default input/output paths
            app.InputmeshpathEditField.Value = app.path_mesh_d;
            app.InputsatellitephotopathEditField.Value = app.path_imag_d;
            app.OutputsavefilepathEditField.Value = app.path_save_d;
            app.OutputpathEditField.Value = app.path_output_d;
            
            % Load custom paths, if any paths to a .mat file, for subsequent uses.
            if exist("EMSO_solarPV_optimizer_custompaths.mat",'file')
                load("EMSO_solarPV_optimizer_custompaths.mat",'path_mesh','path_imag','path_save','path_output');
                app.InputmeshpathEditField.Value = path_mesh;
                app.InputsatellitephotopathEditField.Value = path_imag;
                app.OutputsavefilepathEditField.Value = path_save;
                app.OutputpathEditField.Value = path_output;
            end
            
            % Initialize Nima's code
            app.clrsky=readtable('Clear-sky-index.xlsx');
            app.clrsky=table2array(app.clrsky);
            app.clrsky = [0;app.clrsky];        % (!) Joseph edit. I assume MATLAB skips the first line of the '.xlsx' file by treating it as a header.
            
            % Refresh Buildings list
            app.refresh_buildings_list
        end

        % Button pushed function: path_meshButton
        function path_meshButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.InputmeshpathEditField.Value = [filepath,'\'];
            end
            app.refresh_buildings_list();
            app.save_paths();
        end

        % Button pushed function: path_imagesButton
        function path_imagesButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.InputsatellitephotopathEditField.Value = [filepath,'\'];
            end
            app.refresh_buildings_list();
            app.save_paths();
        end

        % Button pushed function: path_outputButton
        function path_outputButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.OutputsavefilepathEditField.Value = [filepath,'\'];
            end
            app.save_paths();
        end

        % Button pushed function: path_outputtransferButton
        function path_outputtransferButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.OutputpathEditField.Value = [filepath,'\'];
            end
            app.save_paths();
        end

        % Value changed function: SelectBuildingsListBox
        function SelectBuildingsListBoxValueChanged(app, event)
            value = app.SelectBuildingsListBox.Value;
            if isempty(value)
                app.STARTButton.Enable = false;
            else
                app.STARTButton.Enable = true;
            end
            app.buildings = value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            u = symunit;
            % Grey out EVERYTHING!
            app.PixelLengthEditField.Enable = false;
            app.ShadowstudyresolutionscaleEditField.Enable = false;
            app.checkbox_shadows.Enable = false;
            app.checkbox_polyapprox.Enable = false;
            app.PanelOrientationDropDown.Enable = false;
            app.PaneltiltEditField.Enable = false;
            app.PanelTiltUnitBox.Enable = false;
            app.PanellengthEditField.Enable = false;
            app.PanelwidthEditField.Enable = false;
            app.PanelUnitDropDown.Enable = false;
            app.WalkwayminwidthEditField.Enable = false;
            app.WalkwayUnitDropDown.Enable = false;
            app.InputmeshpathEditField.Enable = false;
            app.InputsatellitephotopathEditField.Enable = false;
            app.OutputsavefilepathEditField.Enable = false;
            app.OutputpathEditField.Enable = false;
            app.path_meshButton.Enable = false;
            app.path_imagesButton.Enable = false;
            app.path_outputButton.Enable = false;
            app.path_outputtransferButton.Enable = false;
            app.SelectBuildingsListBox.Enable = false;
            app.ResetPathstoDefaultButton.Enable = false;
            app.GenerateNew3DModelsButton.Enable = false;
            app.checkbox_plot.Enable = false;
            app.checkbox_allow_custom_mask.Enable = false;
            
            % Disable Start button; alter text
            app.STARTButton.Text = "RUNNING";
            app.STARTButton.Enable = false;
            
            pause(1);           % Prevents Buildings from generating before UI is greyed out    
            
            
            % Set up units
            switch app.PanelUnitDropDown.Value
                case "inches"
                    panel_units = u.in;
                case "feet"
                    panel_units = u.ft;
                case "centimetres"
                    panel_units = u.cm;
                case "metres"
                    panel_units = u.m;
                otherwise
                    panel_units = u.in;
                    error(app.PanelUnitDropDown.Value+" not functional!");                    
            end
            switch app.WalkwayUnitDropDown.Value
                case "metres"
                    walkway_units = u.m;
                case "feet"
                    walkway_units = u.ft;
                otherwise
                    error(app.WalkwayUnitDropDown.Value+" not functional!");
            end
            switch app.PanelTiltUnitBox.Text
                case "degrees"
                    tilt_units = u.deg;
                otherwise
                    error(app.PanelTiltUnitBox.Text+" has incorrect units.")
            end
            
            % Initialize Building parameters
            pixlength = app.PixelLengthEditField.Value;
                panel_y = app.PanellengthEditField.Value*panel_units;
                panel_x = app.PanelwidthEditField.Value*panel_units;
            panelSpecs = [panel_y,panel_x];
            panelTilt = app.PaneltiltEditField.Value * tilt_units;
            panelOrientation = lower(app.PanelOrientationDropDown.Value);
            walkway_length = app.WalkwayminwidthEditField.Value * walkway_units;
            force_run_poly = app.checkbox_polyapprox.Value;
            force_run_shadow = app.checkbox_shadows.Value;
            allow_custom_mask = app.checkbox_allow_custom_mask.Value;
            shadow_scale = app.ShadowstudyresolutionscaleEditField.Value;
            % Path names
            input_path_meshes = app.InputmeshpathEditField.Value;
            input_path_photos = app.InputsatellitephotopathEditField.Value;
            output_path_save  = app.OutputsavefilepathEditField.Value;
            output_path = app.OutputpathEditField.Value;
%             if input_path_meshes(end) ~= '/'
%                 input_path_meshes = [input_path_meshes,'/'];
%             end
            if ~ismember(input_path_meshes(end), ['/','\'])
                input_path_meshes = [input_path_meshes,'/'];
            end
            if ~ismember(input_path_photos(end), ['/','\'])
                input_path_photos = [input_path_photos,'/'];
            end
            if ~ismember(output_path_save(end), ['/','\'])
                output_path_save = [output_path_save,'/'];
            end
            if ~ismember(output_path(end), ['/','\'])
                output_path = [output_path,'/'];
            end
            % Building selection
            value = app.SelectBuildingsListBox.Value;
            app.buildings = value;
            
            % Generate all information for each selected building!
            for i = 1:length(app.buildings)
                name = convertCharsToStrings(app.buildings{i});
                
                % Get latitude
                fname_lats = app.OutputsavefilepathEditField.Value +"EMSO_solarPV_buildings.mat";
                if exist(fname_lats,'file')
                    load(fname_lats,"names_saved","latitudes_saved");
                    idx = ismember(names_saved,name);
                    lat = latitudes_saved(idx);
                    if ismember(class(lat),["string","char"]) && ~isempty(lat)
                        lat = str2num(lat);
                    else
                        warning(fname_lats+" does not exist, so latitude is unknown! Reverting to default latitude of 53.5281333.")
                        lat = 53.5281333;
                    end
                else
                    warning(fname_lats+" does not exist, so latitude is unknown! Reverting to default latitude of 53.5281333.")
                    lat = 53.5281333;
                end
                
                % Run Joseph's code
                disp(name+": Generating `Building` object.");
                tic
                b = Building(name,'pixlength',pixlength,'panelSpecs',panelSpecs,'panelTilt',panelTilt,'panelOrientation',panelOrientation, ...
                    'walkway_length',walkway_length,'force_run_poly',force_run_poly,'lat',lat, ...
                    'force_run_shadow',force_run_shadow,'shadow_scale',shadow_scale, ...
                    'allow_custom_mask',allow_custom_mask, ...
                    'input_path_meshes',input_path_meshes,'input_path_photos',input_path_photos, ...
                    'output_path_save',output_path_save,'output_path',output_path);
                toc
                
                % Save output
                eval("table = b.info");
                eval("pix2m = b.pix2m;");
                eval("shadows = b.shadows;");
                eval("heightmap = b.Z;");
                save(output_path+name+"_info.mat","table","pix2m","shadows","heightmap");
                
                % Generate figures, if specified
                if app.checkbox_plot.Value == true
                    plot_figures(b,pixlength,lat,output_path,output_path_save);
                end
                
                % Run Nima's code
                disp(name+": Optimizing rooftop generation...");
                generate_tables(name,app.clrsky,output_path);
                disp(name+": ... finished!");
            end            
            
            
            % Reenable EVERYTHING!
%             app.LatitudeEditField.Enable = true;
            app.PixelLengthEditField.Enable = true;
            app.ShadowstudyresolutionscaleEditField.Enable = true;
            app.checkbox_shadows.Enable = true;
            app.checkbox_polyapprox.Enable = true;
            app.PanelOrientationDropDown.Enable = true;
            app.PaneltiltEditField.Enable = true;
%             app.PanelTiltUnitBox.Enable = true;
            app.PanellengthEditField.Enable = true;
            app.PanelwidthEditField.Enable = true;
            app.PanelUnitDropDown.Enable = true;
            app.WalkwayminwidthEditField.Enable = true;
            app.WalkwayUnitDropDown.Enable = true;
            app.InputmeshpathEditField.Enable = true;
            app.InputsatellitephotopathEditField.Enable = true;
            app.OutputsavefilepathEditField.Enable = true;
            app.OutputpathEditField.Enable = true;
            app.path_meshButton.Enable = true;
            app.path_imagesButton.Enable = true;
            app.path_outputButton.Enable = true;
            app.path_outputtransferButton.Enable = true;
            app.SelectBuildingsListBox.Enable = true;
            app.ResetPathstoDefaultButton.Enable = true;
            app.GenerateNew3DModelsButton.Enable = true;
            app.checkbox_plot.Enable = true;
            app.checkbox_allow_custom_mask.Enable = true;
            
            % Enable Start button; alter text
            app.STARTButton.Text = "START";
            app.STARTButton.Enable = true;
        end

        % Callback function: OutputpathEditField, OutputpathEditField
        function OutputpathEditFieldValueChanged(app, event)
            app.save_paths();
        end

        % Value changed function: InputmeshpathEditField
        function InputmeshpathEditFieldValueChanged(app, event)
            app.refresh_buildings_list();
            app.save_paths();
        end

        % Value changed function: InputsatellitephotopathEditField
        function InputsatellitephotopathEditFieldValueChanged(app, event)
            app.refresh_buildings_list();
            app.save_paths();
        end

        % Value changing function: InputmeshpathEditField
        function InputmeshpathEditFieldValueChanging(app, event)
            app.refresh_buildings_list();
            app.save_paths();
        end

        % Value changing function: InputsatellitephotopathEditField
        function InputsatellitephotopathEditFieldValueChanging(app, event)
            app.refresh_buildings_list();
            app.save_paths();
        end

        % Button pushed function: GenerateNew3DModelsButton
        function GenerateNew3DModelsButtonPushed(app, event)
            EMSO_solarPV_model_generator
            close(app.RooftopPVOptimizerforEMSOUIFigure)
        end

        % Button pushed function: ResetPathstoDefaultButton
        function ResetPathstoDefaultButtonPushed(app, event)
            % Populate default input/output paths
            app.InputmeshpathEditField.Value = app.path_mesh_d;
            app.InputsatellitephotopathEditField.Value = app.path_imag_d;
            app.OutputsavefilepathEditField.Value = app.path_save_d;
            app.OutputpathEditField.Value = app.path_output_d;
            
            app.refresh_buildings_list();
            app.save_paths();
        end

        % Value changed function: OutputsavefilepathEditField
        function OutputsavefilepathEditFieldValueChanged(app, event)
            app.save_paths();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RooftopPVOptimizerforEMSOUIFigure = uifigure('Visible', 'off');
            app.RooftopPVOptimizerforEMSOUIFigure.Name = 'Rooftop PV Optimizer for EMSO';
            app.GridLayout = uigridlayout(app.RooftopPVOptimizerforEMSOUIFigure);
            app.APPSettingsLabel = uilabel(app.GridLayout);
            app.APPSettingsLabel.Text = 'APP Settings';
            app.PixelLengthEditFieldLabel = uilabel(app.GridLayout);
            app.PixelLengthEditFieldLabel.Tooltip = {'Number of pixels comprising length of height map.'};
            app.PixelLengthEditFieldLabel.Text = 'Pixel Length';
            app.PixelLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixelLengthEditField.ValueDisplayFormat = '%.0f';
            app.PixelLengthEditField.Tooltip = {''};
            app.PixelLengthEditField.Value = 1600;
            app.PANELWALKWAYSettingsLabel = uilabel(app.GridLayout);
            app.PANELWALKWAYSettingsLabel.Text = 'PANEL+WALKWAY Settings';
            app.PanelUnitDropDown = uidropdown(app.GridLayout);
            app.PanelUnitDropDown.Items = {'inches', 'feet', 'centimetres', 'metres'};
            app.PanelUnitDropDown.Value = 'inches';
            app.PanellengthEditFieldLabel = uilabel(app.GridLayout);
            app.PanellengthEditFieldLabel.Text = 'Panel length';
            app.PanellengthEditField = uieditfield(app.GridLayout, 'text');
            app.PanellengthEditField.Value = '65';
            app.PanelwidthEditFieldLabel = uilabel(app.GridLayout);
            app.PanelwidthEditFieldLabel.Text = 'Panel width';
            app.PanelwidthEditField = uieditfield(app.GridLayout, 'text');
            app.PanelwidthEditField.Value = '39';
            app.PaneltiltEditFieldLabel = uilabel(app.GridLayout);
            app.PaneltiltEditFieldLabel.Text = 'Panel tilt';
            app.PaneltiltEditField = uieditfield(app.GridLayout, 'numeric');
            app.PanelTiltUnitBox = uibutton(app.GridLayout, 'push');
            app.PanelTiltUnitBox.Enable = 'off';
            app.PanelTiltUnitBox.Text = 'degrees';
            app.PanelOrientationDropDown = uidropdown(app.GridLayout);
            app.PanelOrientationDropDown.Items = {'Landscape', 'Portrait'};
            app.PanelOrientationDropDown.Value = 'Landscape';
            app.WalkwayminwidthEditFieldLabel = uilabel(app.GridLayout);
            app.WalkwayminwidthEditFieldLabel.Tooltip = {'Legally required minimum distance from panel to rooftop edge.'};
            app.WalkwayminwidthEditFieldLabel.Text = 'Walkway min. width';
            app.WalkwayminwidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.WalkwayminwidthEditField.Tooltip = {'Legally required minimum distance from panel to rooftop edge.'};
            app.WalkwayminwidthEditField.Value = 1.8;
            app.WalkwayUnitDropDown = uidropdown(app.GridLayout);
            app.WalkwayUnitDropDown.Items = {'metres', 'feet'};
            app.WalkwayUnitDropDown.Value = 'metres';
            app.ForcepolygonapproximationLabel = uilabel(app.GridLayout);
            app.ForcepolygonapproximationLabel.Tooltip = {'Forces polygon approx. to run regardless of whether saved files exist.'};
            app.ForcepolygonapproximationLabel.Text = {'Force polygon '; 'approximation'};
            app.SelectBuildingsListBoxLabel = uilabel(app.GridLayout);
            app.SelectBuildingsListBoxLabel.Tooltip = {'Buildings for which 3D models and satellite photos are available in specified folders.'};
            app.SelectBuildingsListBoxLabel.Text = 'Select Building(s)';
            app.SelectBuildingsListBox = uilistbox(app.GridLayout);
            app.SelectBuildingsListBox.Multiselect = 'on';
            app.SelectBuildingsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectBuildingsListBoxValueChanged, true);
            app.SelectBuildingsListBox.Tooltip = {'Buildings for which 3D models and satellite photos are available in specified folders.'};
            app.SelectBuildingsListBox.Value = {'Item 1'};
            app.checkbox_polyapprox = uicheckbox(app.GridLayout);
            app.checkbox_polyapprox.Text = '';
            app.ForceshadowstudyLabel = uilabel(app.GridLayout);
            app.ForceshadowstudyLabel.Tooltip = {'Forces shadow study to run from scratch regardless of whether saved file exists.'};
            app.ForceshadowstudyLabel.Text = 'Force shadow study';
            app.checkbox_shadows = uicheckbox(app.GridLayout);
            app.checkbox_shadows.Text = '';
            app.ShadowstudyresolutionscaleEditFieldLabel = uilabel(app.GridLayout);
            app.ShadowstudyresolutionscaleEditFieldLabel.Tooltip = {'Resolution scale (from 0 to 1) of heightmap when calculating shadows.'; 'Higher values are slower.'};
            app.ShadowstudyresolutionscaleEditFieldLabel.Text = {'Shadow study'; 'resolution scale'};
            app.ShadowstudyresolutionscaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.ShadowstudyresolutionscaleEditField.Tooltip = {''};
            app.ShadowstudyresolutionscaleEditField.Value = 0.3;
            app.path_meshButton = uibutton(app.GridLayout, 'push');
            app.path_meshButton.ButtonPushedFcn = createCallbackFcn(app, @path_meshButtonPushed, true);
            app.path_meshButton.Tooltip = {'Path to input building meshes (.STL).'};
            app.path_meshButton.Text = 'Browse';
            app.path_imagesButton = uibutton(app.GridLayout, 'push');
            app.path_imagesButton.ButtonPushedFcn = createCallbackFcn(app, @path_imagesButtonPushed, true);
            app.path_imagesButton.Tooltip = {'Path to input satellite photos (.png or .jpg).'};
            app.path_imagesButton.Text = 'Browse';
            app.path_outputButton = uibutton(app.GridLayout, 'push');
            app.path_outputButton.ButtonPushedFcn = createCallbackFcn(app, @path_outputButtonPushed, true);
            app.path_outputButton.Tooltip = {'Output folder containing "save" files between runs.'};
            app.path_outputButton.Text = 'Browse';
            app.path_outputtransferButton = uibutton(app.GridLayout, 'push');
            app.path_outputtransferButton.ButtonPushedFcn = createCallbackFcn(app, @path_outputtransferButtonPushed, true);
            app.path_outputtransferButton.Tooltip = {'Output path containing final tables & rooftop information.'};
            app.path_outputtransferButton.Text = 'Browse';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tooltip = {'Generates all information for each selected building, one at a time.'};
            app.STARTButton.Text = 'START';
            app.PanelorientationLabel = uilabel(app.GridLayout);
            app.PanelorientationLabel.Text = 'Panel orientation';
            app.InputmeshpathEditFieldLabel = uilabel(app.GridLayout);
            app.InputmeshpathEditFieldLabel.Tooltip = {'Path to input building meshes (.STL).'};
            app.InputmeshpathEditFieldLabel.Text = 'Input mesh path';
            app.InputmeshpathEditField = uieditfield(app.GridLayout, 'text');
            app.InputmeshpathEditField.ValueChangedFcn = createCallbackFcn(app, @InputmeshpathEditFieldValueChanged, true);
            app.InputmeshpathEditField.ValueChangingFcn = createCallbackFcn(app, @InputmeshpathEditFieldValueChanging, true);
            app.InputmeshpathEditField.Editable = 'off';
            app.InputmeshpathEditField.Tooltip = {'Path to input building meshes (.STL).'};
            app.InputsatellitephotopathEditFieldLabel = uilabel(app.GridLayout);
            app.InputsatellitephotopathEditFieldLabel.Tooltip = {'Path to input satellite photos (.png or .jpg).'};
            app.InputsatellitephotopathEditFieldLabel.Text = 'Input satellite photo path';
            app.InputsatellitephotopathEditField = uieditfield(app.GridLayout, 'text');
            app.InputsatellitephotopathEditField.ValueChangedFcn = createCallbackFcn(app, @InputsatellitephotopathEditFieldValueChanged, true);
            app.InputsatellitephotopathEditField.ValueChangingFcn = createCallbackFcn(app, @InputsatellitephotopathEditFieldValueChanging, true);
            app.InputsatellitephotopathEditField.Editable = 'off';
            app.InputsatellitephotopathEditField.Tooltip = {'Path to input satellite photos (.png or .jpg).'};
            app.OutputsavefilepathEditFieldLabel = uilabel(app.GridLayout);
            app.OutputsavefilepathEditFieldLabel.Tooltip = {'Output folder containing "save" files between runs.'};
            app.OutputsavefilepathEditFieldLabel.Text = 'Output savefile path';
            app.OutputsavefilepathEditField = uieditfield(app.GridLayout, 'text');
            app.OutputsavefilepathEditField.ValueChangedFcn = createCallbackFcn(app, @OutputsavefilepathEditFieldValueChanged, true);
            app.OutputsavefilepathEditField.Editable = 'off';
            app.OutputsavefilepathEditField.Tooltip = {'Output folder containing "save" files between runs.'};
            app.OutputpathEditFieldLabel = uilabel(app.GridLayout);
            app.OutputpathEditFieldLabel.Tooltip = {'Output path containing final tables & rooftop information.'};
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.GridLayout, 'text');
            app.OutputpathEditField.ValueChangedFcn = createCallbackFcn(app, @OutputpathEditFieldValueChanged, true);
            app.OutputpathEditField.ValueChangingFcn = createCallbackFcn(app, @OutputpathEditFieldValueChanged, true);
            app.OutputpathEditField.Editable = 'off';
            app.OutputpathEditField.Tooltip = {'Output path containing final tables, rooftop information, and plots.'};
            app.GenerateNew3DModelsButton = uibutton(app.GridLayout, 'push');
            app.GenerateNew3DModelsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNew3DModelsButtonPushed, true);
            app.GenerateNew3DModelsButton.Tooltip = {'Runs app for automatic 3D model / 2D image generation at user-specified world location.'};
            app.GenerateNew3DModelsButton.Text = {'Generate New'; '3D Models'};
            app.ResetPathstoDefaultButton = uibutton(app.GridLayout, 'push');
            app.ResetPathstoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPathstoDefaultButtonPushed, true);
            app.ResetPathstoDefaultButton.Text = 'Reset Paths to Default';
            app.GeneratefiguresLabel = uilabel(app.GridLayout);
            app.GeneratefiguresLabel.Tooltip = {'Outputs various plots into output path.'};
            app.GeneratefiguresLabel.Text = 'Generate figures';
            app.checkbox_plot = uicheckbox(app.GridLayout);
            app.checkbox_plot.Text = '';
            app.AllowcustommaskLabel = uilabel(app.GridLayout);
            app.AllowcustommaskLabel.Tooltip = {'Toggles whether to allow user to use their own mask, or whether to use the automatically-generated one.'};
            app.AllowcustommaskLabel.Text = 'Allow custom mask';
            app.checkbox_allow_custom_mask = uicheckbox(app.GridLayout);
            app.checkbox_allow_custom_mask.Text = '';
            app.checkbox_allow_custom_mask.Value = true;
            % Show the figure after all components are created
            app.RooftopPVOptimizerforEMSOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMSO_solarPV_optimizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RooftopPVOptimizerforEMSOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RooftopPVOptimizerforEMSOUIFigure)
        end
    end
end